classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LimpiarButton             matlab.ui.control.Button
        EliminarButton            matlab.ui.control.Button
        AgregarButton             matlab.ui.control.Button
        CantidadDiasEditField     matlab.ui.control.NumericEditField
        DiaspromediodeusoalasemanaEditFieldLabel  matlab.ui.control.Label
        UsoEnHorasEditField       matlab.ui.control.NumericEditField
        UsopromediodiarioenhorasEditFieldLabel  matlab.ui.control.Label
        PotenciaEnWattsEditField  matlab.ui.control.NumericEditField
        PotenciaqueconsumeenWattsEditFieldLabel  matlab.ui.control.Label
        TipoDropDown              matlab.ui.control.DropDown
        TipoDropDownLabel         matlab.ui.control.Label
        ResultadoLabel            matlab.ui.control.Label
        EquiposElectricosDomesticosLabel  matlab.ui.control.Label
        CalcularPotenciaButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Total = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarCallback(app, event)
            consumo = app.PotenciaEnWattsEditField.Value;
            horas = app.UsoEnHorasEditField.Value;
            dias = app.CantidadDiasEditField.Value;
            app.ResultadoLabel.Visible = "on";
            tipo = app.TipoDropDown.Value;
            if dias > 7 || dias < 1
                app.ResultadoLabel.Text = "Ingrese un número de días válido";
            elseif horas > 24 || horas < 1
                app.ResultadoLabel.Text = "Ingrese un número de horas válido";
            elseif consumo < 1
                app.ResultadoLabel.Text = "Ingrese un consumo válido";
            else
                %consumo*horas*dias*4
                consumo = consumo * horas * dias * 4;
                %no es = *.60; escencial = *.80; muy es = *1
                if tipo == "Esencial"
                    consumo = consumo * .80;
                end
                if tipo == "No Esencial"
                    consumo = consumo * .60;
                end
                app.Total = app.Total + consumo;
                consumoTotalStr = num2str("El consumo de este producto al mes es: "+consumo+" Watts");%convierte a str
                app.ResultadoLabel.Text = consumoTotalStr;
            end
        end

        % Button pushed function: CalcularPotenciaButton
        function CalcularTotalCallback(app, event)
            consumoTotalStr = num2str("El total es: "+app.Total+" Watts");
            app.ResultadoLabel.Text = consumoTotalStr;
        end

        % Button pushed function: LimpiarButton
        function LimpiarCallback(app, event)
            app.ResultadoLabel.Visible = "off";
            app.Total = 0;
        end

        % Button pushed function: EliminarButton
        function EliminarCallback(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalcularPotenciaButton = uibutton(app.UIFigure, 'push');
            app.CalcularPotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTotalCallback, true);
            app.CalcularPotenciaButton.Text = 'Calcular Potencia';
            app.EquiposElectricosDomesticosLabel = uilabel(app.UIFigure);
            app.EquiposElectricosDomesticosLabel.Text = 'Equipos Electricos Domesticos';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.TipoDropDownLabel = uilabel(app.UIFigure);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.UIFigure);
            app.TipoDropDown.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.TipoDropDown.Value = 'Esencial';
            app.PotenciaqueconsumeenWattsEditFieldLabel = uilabel(app.UIFigure);
            app.PotenciaqueconsumeenWattsEditFieldLabel.Text = 'Potencia que consume (en Watts)';
            app.PotenciaEnWattsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsopromediodiarioenhorasEditFieldLabel = uilabel(app.UIFigure);
            app.UsopromediodiarioenhorasEditFieldLabel.Text = 'Uso promedio diario (en horas)';
            app.UsoEnHorasEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiaspromediodeusoalasemanaEditFieldLabel = uilabel(app.UIFigure);
            app.DiaspromediodeusoalasemanaEditFieldLabel.Text = 'Dias promedio de uso a la semana';
            app.CantidadDiasEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarCallback, true);
            app.EliminarButton.Text = 'Eliminar';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarCallback, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end